classdef ECE_6160_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PowerGridImage               matlab.ui.control.Image
        HeaderLabel                  matlab.ui.control.Label
        TransmissionLineStatusLabel  matlab.ui.control.Label
        TL1LampLabel                 matlab.ui.control.Label
        TL1Lamp                      matlab.ui.control.Lamp
        TL21LampLabel                matlab.ui.control.Label
        TL21Lamp                     matlab.ui.control.Lamp
        TL2LampLabel                 matlab.ui.control.Label
        TL2Lamp                      matlab.ui.control.Lamp
        TL22LampLabel                matlab.ui.control.Label
        TL22Lamp                     matlab.ui.control.Lamp
        TL23LampLabel                matlab.ui.control.Label
        TL23Lamp                     matlab.ui.control.Lamp
        TL123LampLabel               matlab.ui.control.Label
        TL123Lamp                    matlab.ui.control.Lamp
        TL3LampLabel                 matlab.ui.control.Label
        TL3Lamp                      matlab.ui.control.Lamp
        TL4LampLabel                 matlab.ui.control.Label
        TL4Lamp                      matlab.ui.control.Lamp
        TL5LampLabel                 matlab.ui.control.Label
        TL5Lamp                      matlab.ui.control.Lamp
        TL6LampLabel                 matlab.ui.control.Label
        TL6Lamp                      matlab.ui.control.Lamp
        TL122LampLabel               matlab.ui.control.Label
        TL122Lamp                    matlab.ui.control.Lamp
        TL24LampLabel                matlab.ui.control.Label
        TL24Lamp                     matlab.ui.control.Lamp
        TL7LampLabel                 matlab.ui.control.Label
        TL7Lamp                      matlab.ui.control.Lamp
        TL25LampLabel                matlab.ui.control.Label
        TL25Lamp                     matlab.ui.control.Lamp
        TL8LampLabel                 matlab.ui.control.Label
        TL8Lamp                      matlab.ui.control.Lamp
        TL26LampLabel                matlab.ui.control.Label
        TL26Lamp                     matlab.ui.control.Lamp
        LiveStatusFeedTextAreaLabel  matlab.ui.control.Label
        Textfeed                     matlab.ui.control.TextArea
        LampTL21                     matlab.ui.control.Lamp
        LampTL1                      matlab.ui.control.Lamp
        LampTL22                     matlab.ui.control.Lamp
        Lamp4                        matlab.ui.control.Lamp
        Lamp5                        matlab.ui.control.Lamp
        LampTL23                     matlab.ui.control.Lamp
        LampTL4                      matlab.ui.control.Lamp
        LampTL3                      matlab.ui.control.Lamp
        LampTL5                      matlab.ui.control.Lamp
        LampTL6                      matlab.ui.control.Lamp
        Lamp11                       matlab.ui.control.Lamp
        Lamp12                       matlab.ui.control.Lamp
        Lamp13                       matlab.ui.control.Lamp
        Lamp14                       matlab.ui.control.Lamp
        Lamp15                       matlab.ui.control.Lamp
        Lamp16                       matlab.ui.control.Lamp
        Label                        matlab.ui.control.Label
        StartOnlineMonitoringButton  matlab.ui.control.Button
        Label_2                      matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        Label_6                      matlab.ui.control.Label
        Label_7                      matlab.ui.control.Label
        Label_8                      matlab.ui.control.Label
        Label_9                      matlab.ui.control.Label
        Label_10                     matlab.ui.control.Label
        Label_11                     matlab.ui.control.Label
        Label_12                     matlab.ui.control.Label
        Label_13                     matlab.ui.control.Label
        Label_14                     matlab.ui.control.Label
        Label_15                     matlab.ui.control.Label
        Label_16                     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           %Start all breakers as down
            app.TL1Lamp.Color = "Red";
            app.TL21Lamp.Color = "Red";
            app.TL2Lamp.Color = "Red";
            app.TL22Lamp.Color = "Red";
            app.TL23Lamp.Color = "Red";
            app.TL123Lamp.Color = "Red";
            app.TL3Lamp.Color = "Red";
            app.TL4Lamp.Color = "Red";
            app.TL5Lamp.Color = "Red";
            app.TL6Lamp.Color = "Red";
            app.TL122Lamp.Color = "Red";
            app.TL24Lamp.Color = "Red";
            app.TL7Lamp.Color = "Red";
            app.TL25Lamp.Color = "Red";
            app.TL8Lamp.Color = "Red";
            app.TL26Lamp.Color = "Red";
           %Repeat this for on image lamps
            app.LampTL21.Color = "Red";
            app.LampTL1.Color = "Red";
            app.LampTL22.Color = "Red";
            app.Lamp4.Color = "Red";
            app.Lamp5.Color = "Red";
            app.LampTL23.Color = "Red";
            app.LampTL4.Color = "Red";
            app.LampTL3.Color = "Red";
            app.LampTL5.Color = "Red";
            app.LampTL6.Color = "Red";
            app.Lamp11.Color = "Red";
            app.Lamp12.Color = "Red";
            app.Lamp13.Color = "Red";
            app.Lamp14.Color = "Red";
            app.Lamp15.Color = "Red";
            app.Lamp16.Color = "Red";
            
            app.Textfeed.Value = "Hello, Welcome to the Visualization Dashboard!"
            pause(4)
            app.Textfeed.Value = "Please hit start to begin online monitoring."
            

        end

        % Button pushed function: StartOnlineMonitoringButton
        function StartOnlineMonitoringButtonPushed(app, event)
            app.Textfeed.Value = "Starting Up Online Monitoring"
            %Bring all the breakers live
            app.TL1Lamp.Color = "Green";
            pause(.2)
            app.TL21Lamp.Color = "Green";
            pause(.2)
            app.TL2Lamp.Color = "Green";
            pause(.2)
            app.TL22Lamp.Color = "Green";
            pause(.2)
            app.TL23Lamp.Color = "Green";
            pause(.2)
            app.TL123Lamp.Color = "Green";
            pause(.2)
            app.TL3Lamp.Color = "Green";
            pause(.2)
            app.TL4Lamp.Color = "Green";
            pause(.2)
            app.TL5Lamp.Color = "Green";
            pause(.2)
            app.TL6Lamp.Color = "Green";
            pause(.2)
            app.TL122Lamp.Color = "Green";
            pause(.2)
            app.TL24Lamp.Color = "Green";
            pause(.2)
            app.TL7Lamp.Color = "Green";
            pause(.2)
            app.TL25Lamp.Color = "Green";
            pause(.2)
            app.TL8Lamp.Color = "Green";
            pause(.2)
            app.TL26Lamp.Color = "Green";
            pause(.2)
           %Repeat this for on image lamps
            app.LampTL21.Color = "Green";
            app.LampTL1.Color = "Green";
            app.LampTL22.Color = "Green";
            app.Lamp4.Color = "Green";
            app.Lamp5.Color = "Green";
            app.LampTL23.Color = "Green";
            app.LampTL4.Color = "Green";
            app.LampTL3.Color = "Green";
            app.LampTL5.Color = "Green";
            app.LampTL6.Color = "Green";
            app.Lamp11.Color = "Green";
            app.Lamp12.Color = "Green";
            app.Lamp13.Color = "Green";
            app.Lamp14.Color = "Green";
            app.Lamp15.Color = "Green";
            app.Lamp16.Color = "Green";
            app.Textfeed.Value = "System is now live";
            pause(2)
            app.Textfeed.Value = "Training the System";
            %Train the KNN Network
                        
            % Index and Weight Matrices
            BR2IW = [17 18 33 34 1 2 9 10;
                2 2 2 2 1 1 1 1];
            BR4IW = [21 22 37 38 3 4 11 12;
                2 2 2 2 1 1 1 1];
            BR5IW = [25 26 41 42 5 6 13 14;
                2 2 2 2 1 1 1 1];
            BR7IW = [29 30 45 46 5 8 13 16;
                2 2 2 2 1 1 1 1];
            BR8IW = [31 32 47 48 5 8 13 16;
                2 2 2 2 1 1 1 1];
            BR9IW = [75 76 91 92 8 53 16 60;
                2 2 2 2 1 1 1 1];
            BR10IW = [77 78 93 94 8 53 16 60;
                2 2 2 2 1 1 1 1];
            
            %<<<<KNN TRAINING>>>>>>
            input = csvread("train_input.csv");
            output = csvread("train_output.csv");
            [rw,clm] = size(output);

            for j=1:rw                  %Rectifying the issue of getting non-binary value for the switching status
                for k=1:clm
                    output(j,k) = output(j,k) /1.4013e-45; %1.4013e-45          % Check this value in the output file and replace the correct value to conver into binary output file, this value might change
                     if(output(j,k)>0)
                        output(j,k)=1;
                     end
                 end
            end

            norms = getNorms(input,output);
            norm_input = normalizeInput(norms,input);

            BR2input = getBRinput(norm_input,BR2IW);
            mod2 = fitcknn(BR2input', output(2,:)',...
                'NumNeighbors', 7, 'DistanceWeight', 'inverse');
            BR4input = getBRinput(norm_input,BR4IW);
            mod4 = fitcknn(BR4input', output(4,:)',...
                'NumNeighbors', 7, 'DistanceWeight', 'inverse');
            BR5input = getBRinput(norm_input,BR5IW);
            mod5 = fitcknn(BR5input', output(5,:)',...
                'NumNeighbors', 7, 'DistanceWeight', 'inverse');
            BR7input = getBRinput(norm_input,BR7IW);
            mod7 = fitcknn(BR7input', output(7,:)',...
                'NumNeighbors', 7, 'DistanceWeight', 'inverse');
            BR8input = getBRinput(norm_input,BR8IW);
            mod8 = fitcknn(BR8input', output(8,:)',...
                'NumNeighbors', 7, 'DistanceWeight', 'inverse');
            BR9input = getBRinput(norm_input,BR9IW);
            mod9 = fitcknn(BR9input', output(9,:)',...
                'NumNeighbors', 7, 'DistanceWeight', 'inverse');
            BR10input = getBRinput(norm_input,BR10IW);
            mod10 = fitcknn(BR10input', output(10,:)',...
                'NumNeighbors', 7, 'DistanceWeight', 'inverse');
            app.Textfeed.Value = "System Train was Successful";
            pause(1)
            app.Textfeed.Value = "Testing will now begin";
            pause(1)
            
            
            %<<<<TESTING>>>>>>
%             test_input = csvread("test_input.csv");
%             test_output = csvread("test_output.csv");
%             [rrw,rclm] = size(test_output);
        for i=1:1:100000
            [test_input] = PMU_Data_Collecting();
            %est_output=zeros(rrw,rclm);
            
%             %Should be able to remove this piece for in lab testing
%             for j=1:rrw                                 %Rectifying the issue of getting non-binary value for the switching status
%                 for k=1:rclm
%                     test_output(j,k) = test_output(j,k) /1.4013e-45; %1.4013e-45          % Check this value in the random_output file and replace the correct value to conver into binary output file, this value might change
%                     if(test_output(j,k)>0)
%                         test_output(j,k)=1;
%                     end
%                 end
%             end


                norm_test_input = normalizeInput(norms,test_input(:));
                BR2_test_input = getBRinput(norm_test_input,BR2IW);
                BR4_test_input = getBRinput(norm_test_input,BR4IW);
                BR5_test_input = getBRinput(norm_test_input,BR5IW);
                BR7_test_input = getBRinput(norm_test_input,BR7IW);
                BR8_test_input = getBRinput(norm_test_input,BR8IW);
                BR9_test_input = getBRinput(norm_test_input,BR9IW);
                BR10_test_input = getBRinput(norm_test_input,BR10IW);
                
                est_output(1,i) = 1;%ones(1,rclm);
                est_output(2,i) = predict(mod2,BR2_test_input');
                est_output(3,i) = 1;%ones(1,rclm);
                est_output(4,i) = predict(mod4,BR4_test_input');
                est_output(5,i) = predict(mod5,BR5_test_input');
                est_output(6,i) = 1;%ones(1,rclm);
                est_output(7,i) = predict(mod7,BR7_test_input');
                est_output(8,i) = predict(mod8,BR8_test_input');
                est_output(9,i) = predict(mod9,BR9_test_input');
                est_output(10,i) = predict(mod10,BR10_test_input');
                est_output(11:16,i) = 1;%ones(rrw-11+1,rclm);
                %Update the line status
                if(est_output(2,i) == 1)
                    app.TL21Lamp.Color = "Green";
                    app.LampTL21.Color = "Green";
                else
                    app.TL21Lamp.Color = "Red";
                    app.LampTL21.Color = "Red";
                    app.Textfeed.Value = "Transmission Line 21 Down. Contingency plan in effect.";
                end
                if(est_output(4,i) == 1)
                    app.TL22Lamp.Color = "Green";
                    app.LampTL22.Color = "Green";
                else
                    app.TL22Lamp.Color = "Red";
                    app.LampTL22.Color = "Red";
                    app.Textfeed.Value = "Transmission Line 22 Down. Contingency plan in effect.";
                end
                if(est_output(5,i) == 1)
                    app.TL23Lamp.Color = "Green";
                    app.LampTL23.Color = "Green";
                else
                    app.TL23Lamp.Color = "Red";
                    app.LampTL23.Color = "Red";
                    app.Textfeed.Value = "Transmission Line 23 Down. Contingency plan in effect.";
                end
                if(est_output(7,i) == 1)
                    app.TL3Lamp.Color = "Green";
                    app.LampTL3.Color = "Green";
                else
                    app.TL3Lamp.Color = "Red";
                    app.LampTL3.Color = "Red";
                    app.Textfeed.Value = "Transmission Line 3 Down. Contingency plan in effect.";
                end
                if(est_output(8,i) == 1)
                    app.TL4Lamp.Color = "Green";
                    app.LampTL4.Color = "Green";
                else
                    app.TL4Lamp.Color = "Red";
                    app.LampTL4.Color = "Red";
                    app.Textfeed.Value = "Transmission Line 4 Down. Contingency plan in effect.";
                end
                if(est_output(9,i) == 1)
                    app.TL5Lamp.Color = "Green";
                    app.LampTL5.Color = "Green";
                else
                    app.TL5Lamp.Color = "Red";
                    app.LampTL5.Color = "Red";
                    app.Textfeed.Value = "Transmission Line 5 Down. Contingency plan in effect.";
                end
                if(est_output(10,i) == 1)
                    app.TL6Lamp.Color = "Green";
                    app.LampTL6.Color = "Green";
                else
                    app.TL6Lamp.Color = "Red";
                    app.LampTL6.Color = "Red";
                    app.Textfeed.Value = "Transmission Line 6 Down. Contingency plan in effect.";
                end
                
                if(est_output(2,i) == 1 && est_output(4,i) == 1 && est_output(5,i) == 1 && est_output(7,i) == 1 && est_output(8,i) == 1 && est_output(9,i) == 1 && est_output(10,i) == 1)
                    app.Textfeed.Value = "All Transmission Lines Are Currently Up"
                end
                
                %app.TestIteration.Value = num2str(i) 
                pause(.00000000001)
            end
            app.Textfeed.Value = "Testing is complete";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PowerGridImage = uiimage(app.UIFigure);
            app.PowerGridImage.ImageSource = 'PowerGrid.png';
            app.HeaderLabel = uilabel(app.UIFigure);
            app.HeaderLabel.Text = 'Identification of Power Systems Network Branch Events';
            app.TransmissionLineStatusLabel = uilabel(app.UIFigure);
            app.TransmissionLineStatusLabel.Text = 'Transmission Line Status';
            app.TL1LampLabel = uilabel(app.UIFigure);
            app.TL1LampLabel.Text = 'TL1';
            app.TL1Lamp = uilamp(app.UIFigure);
            app.TL21LampLabel = uilabel(app.UIFigure);
            app.TL21LampLabel.Text = 'TL21';
            app.TL21Lamp = uilamp(app.UIFigure);
            app.TL2LampLabel = uilabel(app.UIFigure);
            app.TL2LampLabel.Text = 'TL2';
            app.TL2Lamp = uilamp(app.UIFigure);
            app.TL22LampLabel = uilabel(app.UIFigure);
            app.TL22LampLabel.Text = 'TL22';
            app.TL22Lamp = uilamp(app.UIFigure);
            app.TL23LampLabel = uilabel(app.UIFigure);
            app.TL23LampLabel.Text = 'TL23';
            app.TL23Lamp = uilamp(app.UIFigure);
            app.TL123LampLabel = uilabel(app.UIFigure);
            app.TL123LampLabel.Text = 'TL123';
            app.TL123Lamp = uilamp(app.UIFigure);
            app.TL3LampLabel = uilabel(app.UIFigure);
            app.TL3LampLabel.Text = 'TL3';
            app.TL3Lamp = uilamp(app.UIFigure);
            app.TL4LampLabel = uilabel(app.UIFigure);
            app.TL4LampLabel.Text = 'TL4';
            app.TL4Lamp = uilamp(app.UIFigure);
            app.TL5LampLabel = uilabel(app.UIFigure);
            app.TL5LampLabel.Text = 'TL5';
            app.TL5Lamp = uilamp(app.UIFigure);
            app.TL6LampLabel = uilabel(app.UIFigure);
            app.TL6LampLabel.Text = 'TL6';
            app.TL6Lamp = uilamp(app.UIFigure);
            app.TL122LampLabel = uilabel(app.UIFigure);
            app.TL122LampLabel.Text = 'TL122';
            app.TL122Lamp = uilamp(app.UIFigure);
            app.TL24LampLabel = uilabel(app.UIFigure);
            app.TL24LampLabel.Text = 'TL24';
            app.TL24Lamp = uilamp(app.UIFigure);
            app.TL7LampLabel = uilabel(app.UIFigure);
            app.TL7LampLabel.Text = 'TL7';
            app.TL7Lamp = uilamp(app.UIFigure);
            app.TL25LampLabel = uilabel(app.UIFigure);
            app.TL25LampLabel.Text = 'TL25';
            app.TL25Lamp = uilamp(app.UIFigure);
            app.TL8LampLabel = uilabel(app.UIFigure);
            app.TL8LampLabel.Text = 'TL8';
            app.TL8Lamp = uilamp(app.UIFigure);
            app.TL26LampLabel = uilabel(app.UIFigure);
            app.TL26LampLabel.Text = 'TL26';
            app.TL26Lamp = uilamp(app.UIFigure);
            app.LiveStatusFeedTextAreaLabel = uilabel(app.UIFigure);
            app.LiveStatusFeedTextAreaLabel.Text = 'Live Status Feed';
            app.Textfeed = uitextarea(app.UIFigure);
            app.LampTL21 = uilamp(app.UIFigure);
            app.LampTL1 = uilamp(app.UIFigure);
            app.LampTL22 = uilamp(app.UIFigure);
            app.Lamp4 = uilamp(app.UIFigure);
            app.Lamp5 = uilamp(app.UIFigure);
            app.LampTL23 = uilamp(app.UIFigure);
            app.LampTL4 = uilamp(app.UIFigure);
            app.LampTL3 = uilamp(app.UIFigure);
            app.LampTL5 = uilamp(app.UIFigure);
            app.LampTL6 = uilamp(app.UIFigure);
            app.Lamp11 = uilamp(app.UIFigure);
            app.Lamp12 = uilamp(app.UIFigure);
            app.Lamp13 = uilamp(app.UIFigure);
            app.Lamp14 = uilamp(app.UIFigure);
            app.Lamp15 = uilamp(app.UIFigure);
            app.Lamp16 = uilamp(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '21';
            app.StartOnlineMonitoringButton = uibutton(app.UIFigure, 'push');
            app.StartOnlineMonitoringButton.ButtonPushedFcn = createCallbackFcn(app, @StartOnlineMonitoringButtonPushed, true);
            app.StartOnlineMonitoringButton.Text = 'Start Online Monitoring';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '22';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '23';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '24';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '25';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '26';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '3';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '5';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '4';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '6';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '1';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '2';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '7';
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '8';
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = '123';
            app.Label_16 = uilabel(app.UIFigure);
            app.Label_16.Text = '122';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ECE_6160_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end